classdef GUI_BTL_Robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab1                         matlab.ui.container.Tab
        timesEditField               matlab.ui.control.NumericEditField
        tsLabel                      matlab.ui.control.Label
        ButtonTrajectoryCubic        matlab.ui.control.Button
        TrapezoidalTab               matlab.ui.container.Tab
        amaxmsEditField              matlab.ui.control.NumericEditField
        amaxmsEditFieldLabel         matlab.ui.control.Label
        vmaxmsEditField              matlab.ui.control.NumericEditField
        vmaxmsEditFieldLabel         matlab.ui.control.Label
        ButtonTrajectoryTrapezoidal  matlab.ui.control.Button
        HomePositionButton           matlab.ui.control.Button
        ButtonClearWorkspace         matlab.ui.control.Button
        ButtonDrawWorkspace          matlab.ui.control.Button
        ButtonLoadRobot              matlab.ui.control.Button
        SVTHLVnNht2212393LuTrngNgha2212223Label  matlab.ui.control.Label
        GVHDTSTrnNgcHuyLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        BitplnMnKthutRobotTN01Label  matlab.ui.control.Label
        Panel_Forward_Kinematic      matlab.ui.container.Panel
        label_cur_theta6             matlab.ui.control.Label
        label_cur_theta5             matlab.ui.control.Label
        label_cur_theta4             matlab.ui.control.Label
        label_cur_theta3             matlab.ui.control.Label
        label_cur_theta2             matlab.ui.control.Label
        label_cur_theta1             matlab.ui.control.Label
        Theta6degLabel               matlab.ui.control.Label
        Theta5degLabel               matlab.ui.control.Label
        Theta4degLabel               matlab.ui.control.Label
        Theta3degLabel               matlab.ui.control.Label
        Theta2degLabel               matlab.ui.control.Label
        Theta1degLabel               matlab.ui.control.Label
        GainTheta6Slider             matlab.ui.control.Slider
        GainTheta6SliderLabel        matlab.ui.control.Label
        GainTheta5Slider             matlab.ui.control.Slider
        GainTheta5SliderLabel        matlab.ui.control.Label
        GainTheta4Slider             matlab.ui.control.Slider
        GainTheta4SliderLabel        matlab.ui.control.Label
        GainTheta3Slider             matlab.ui.control.Slider
        GainTheta3SliderLabel        matlab.ui.control.Label
        GainTheta2Slider             matlab.ui.control.Slider
        GainTheta2SliderLabel        matlab.ui.control.Label
        GainTheta1Slider             matlab.ui.control.Slider
        GainTheta1SliderLabel        matlab.ui.control.Label
        Panel_CurrentPosition        matlab.ui.container.Panel
        Val_CurYaw                   matlab.ui.control.Label
        Val_CurPitch                 matlab.ui.control.Label
        Val_CurRoll                  matlab.ui.control.Label
        YawdegLabel                  matlab.ui.control.Label
        PitchdegLabel                matlab.ui.control.Label
        RolldegLabel                 matlab.ui.control.Label
        Val_CurZ                     matlab.ui.control.Label
        Val_CurY                     matlab.ui.control.Label
        Val_CurX                     matlab.ui.control.Label
        ZmLabel                      matlab.ui.control.Label
        YmLabel                      matlab.ui.control.Label
        XmLabel                      matlab.ui.control.Label
        InverseKinematicsPanel       matlab.ui.container.Panel
        Edit_TargZ                   matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        Edit_TargY                   matlab.ui.control.NumericEditField
        YLabel                       matlab.ui.control.Label
        Edit_TargX                   matlab.ui.control.NumericEditField
        XLabel                       matlab.ui.control.Label
        ButtonCalculateIK            matlab.ui.control.Button
        Slider_TargYaw               matlab.ui.control.Slider
        YawLabel                     matlab.ui.control.Label
        Slider_TargPitch             matlab.ui.control.Slider
        PitchLabel                   matlab.ui.control.Label
        Slider_TargRoll              matlab.ui.control.Slider
        RollLabel                    matlab.ui.control.Label
        UIAxes_Acc                   matlab.ui.control.UIAxes
        UIAxes_Vel                   matlab.ui.control.UIAxes
        UIAxes_Pos                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
           robot         % imported URDF
           robotFigure   % handle popup figure
           robotAxes     % handle axes trong figure popup
           XL
           YL
           ZL
           %offset_J1 = 0;
           offset_J1 = -0.081752736;
           offset_J2 = 1.192322759;
           offset_J3 = 0.253006290;
           offset_J4 = 0.000687076;
           offset_J5 = -0.152414968;
           offset_J6 = 0.0;
           cur_theta1
           cur_theta2
           cur_theta3
           cur_theta4
           cur_theta5
           cur_theta6
           traj_Time       % Mảng thời gian t
           traj_t
           traj_Pos        % Ma trận vị trí [x, y, z] (n hàng x 3 cột)
           traj_Vel        % Ma trận vận tốc [vx, vy, vz]
           traj_Acc        % Ma trận gia tốc [ax, ay, az]
           workspacePlotHandle  % Lưu đối tượng vẽ Workspace
           pathPoints = [] % Lưu trữ tọa độ [x, y, z] đã đi qua
    end
    
    methods (Access = private)
       %======================================================================
       % --- HÀM TÍNH ĐỘNG HỌC THUẬN  ---
        function T = calculateFK(~, q)
            % Tính toán ma trận biến đổi thuần nhất T6_0 bằng bảng DH Standard
            % q: Vector 1x6 chứa góc khớp (radian)
            
            % Bảng DH Standard FANUC M-20iA/35M (Cập nhật chuẩn Spong):
            % Link | a (m) | alpha (rad)| d (m)  | theta
            % 1    | 0.150 | pi/2       | 0.525  | q1
            % 2    | 0.790 | 0          | 0      | q2
            % 3    | 0.150 | pi/2       | 0      | q3
            % 4    | 0     | -pi/2      | 0.860  | q4
            % 5    | 0     | pi/2       | 0      | q5
            % 6    | 0     | 0          | 0.100  | q6
            
            c = cos(q);
            s = sin(q);
            
            % Ma trận A1 (a=0.150, alpha=90, d=0.525)
            % cos(90)=0, sin(90)=1
            A1 = [c(1)  -s(1)*0   s(1)*1   0.150*c(1);
                  s(1)   c(1)*0  -c(1)*1   0.150*s(1);
                  0      1        0        0.525;
                  0      0        0        1];
                  
            % Ma trận A2 (a=0.790, alpha=0, d=0)
            A2 = [c(2)  -s(2)     0        0.790*c(2);
                  s(2)   c(2)     0        0.790*s(2);
                  0      0        1        0;
                  0      0        0        1];
                  
            % Ma trận A3 (a=0.150, alpha=90, d=0)
            % cos(90)=0, sin(90)=1
            A3 = [c(3)  -s(3)*0   s(3)*1   0.150*c(3);
                  s(3)   c(3)*0  -c(3)*1   0.150*s(3);
                  0      1        0        0;
                  0      0        0        1];
                  
            % Ma trận A4 (a=0, alpha=-90, d=0.860)
            % cos(-90)=0, sin(-90)=-1
            A4 = [c(4)  -s(4)*0  -s(4)*1   0;
                  s(4)   c(4)*0   c(4)*1   0;
                  0     -1        0        0.860;
                  0      0        0        1];
                  
            % Ma trận A5 (a=0, alpha=90, d=0)
            A5 = [c(5)  -s(5)*0   s(5)*1   0;
                  s(5)   c(5)*0  -c(5)*1   0;
                  0      1        0        0;
                  0      0        0        1];
                  
            % Ma trận A6 (a=0, alpha=0, d=0.100)
            A6 = [c(6)  -s(6)     0        0;
                  s(6)   c(6)     0        0;
                  0      0        1        0.100;
                  0      0        0        1];
            
            % Nhân chuỗi ma trận
            T = A1 * A2 * A3 * A4 * A5 * A6;
        end
    %====================================================================  
    function q_gain = calculateIK_Internal(app, targetPos, targetRPY)
    % targetPos: [x, y, z]
    % targetRPY: [roll, pitch, yaw] (độ)
    
    x = targetPos(1); y = targetPos(2); z = targetPos(3);
    roll = deg2rad(targetRPY(1)); pitch = deg2rad(targetRPY(2)); yaw = deg2rad(targetRPY(3));

    % === TÍNH MA TRẬN R_TOOL ===
    Rx = [1 0 0; 0 cos(roll) -sin(roll); 0 sin(roll) cos(roll)];
    Ry = [cos(pitch) 0 sin(pitch); 0 1 0; -sin(pitch) 0 cos(pitch)];
    Rz = [cos(yaw) -sin(yaw) 0; sin(yaw) cos(yaw) 0; 0 0 1];
    R_tool = Rz * Ry * Rx;
    ax = R_tool(1,3); ay = R_tool(2,3); az = R_tool(3,3);

    % === THÔNG SỐ ROBOT ===
    a1 = 0.150; d1 = 0.525; a2 = 0.790; a3 = 0.150; d4 = 0.860; d6 = 0.100;

    % === TÂM CỔ TAY ===
    xc = x - d6 * ax; yc = y - d6 * ay; zc = z - d6 * az;

    % === GIẢI VỊ TRÍ (Theta 1, 2, 3) ===
    theta1 = atan2(yc, xc);
    r_eff = sqrt(xc^2 + yc^2) - a1;
    z_eff = zc - d1;
    D_sq = r_eff^2 + z_eff^2;
    L34_sq = a3^2 + d4^2; L34 = sqrt(L34_sq);
    a2_sq = a2^2;

    cos_phi3 = (a2_sq + L34_sq - D_sq) / (2 * a2 * L34);
    if abs(cos_phi3) > 1, q_gain = []; return; end % Ngoài tầm với
    
    phi3 = atan2(sqrt(1 - cos_phi3^2), cos_phi3);
    beta = atan2(a3, d4);
    theta3 = phi3 - beta - pi/2;

    alpha_ang = atan2(z_eff, r_eff);
    cos_psi = (a2_sq + D_sq - L34_sq) / (2 * a2 * sqrt(D_sq));
    psi = atan2(sqrt(1 - max(-1, min(1, cos_psi^2))), cos_psi);
    theta2 = alpha_ang + psi;

    % === GIẢI ĐỊNH HƯỚNG (Theta 4, 5, 6) ===
    c1 = cos(theta1); s1 = sin(theta1);
    c2 = cos(theta2); s2 = sin(theta2);
    c3 = cos(theta3); s3 = sin(theta3);
    A1 = [c1 -s1*0 s1*1 a1*c1; s1 c1*0 -c1*1 a1*s1; 0 1 0 d1; 0 0 0 1];
    A2 = [c2 -s2 0 a2*c2; s2 c2 0 a2*s2; 0 0 1 0; 0 0 0 1];
    A3 = [c3 -s3*0 s3*1 a3*c3; s3 c3*0 -c3*1 a3*s3; 0 1 0 0; 0 0 0 1];
    R3_0 = (A1 * A2 * A3); R3_0 = R3_0(1:3,1:3);
    R_wrist = R3_0' * R_tool;

    theta5 = atan2(sqrt(R_wrist(1,3)^2 + R_wrist(2,3)^2), R_wrist(3,3));
    if abs(sin(theta5)) > 1e-4
        theta4 = atan2(R_wrist(2,3), R_wrist(1,3));
        theta6 = atan2(R_wrist(3,2), -R_wrist(3,1));
    else
        theta4 = 0; % Giả định khi kỳ dị
        theta6 = atan2(-R_wrist(1,2), R_wrist(1,1));
    end

    % === BÙ OFFSET TRẢ VỀ GIÁ TRỊ GAIN (ĐỘ) ===
    q_rad = [theta1, theta2, theta3, theta4, theta5, theta6];
    offsets = [app.offset_J1, app.offset_J2, app.offset_J3, app.offset_J4, app.offset_J5, app.offset_J6];
    q_gain = rad2deg(wrapToPi(q_rad - offsets));
    end
    %====================================================================
    %TINH MA TRAN JACOBIAN
    function J = calculateJacobian(app, q)
    % q: vector góc khớp hiện tại (radian) - đã bao gồm offset
    
    % Thông số DH Standard
    a = [0.150, 0.790, 0.150, 0, 0, 0];
    alpha = [pi/2, 0, pi/2, -pi/2, pi/2, 0];
    d = [0.525, 0, 0, 0.860, 0, 0.100];
    
    % Khởi tạo các ma trận biến đổi
    T = cell(1, 6);
    T_curr = eye(4);
    
    % Tính chuỗi ma trận biến đổi từ 0 đến 6
    for i = 1:6
        A = [cos(q(i)) -sin(q(i))*cos(alpha(i))  sin(q(i))*sin(alpha(i)) a(i)*cos(q(i));
             sin(q(i))  cos(q(i))*cos(alpha(i)) -cos(q(i))*sin(alpha(i)) a(i)*sin(q(i));
             0          sin(alpha(i))           cos(alpha(i))        d(i);
             0          0                       0                    1];
        T_curr = T_curr * A;
        T{i} = T_curr;
    end
    
    P_ee = T{6}(1:3, 4); % Tọa độ điểm cuối (End-effector)
    J = zeros(6, 6);
    
    % Khớp 1 (z0 = [0;0;1], P0 = [0;0;0])
    z0 = [0; 0; 1];
    p0 = [0; 0; 0];
    J(1:3, 1) = cross(z0, (P_ee - p0));
    J(4:6, 1) = z0;
    
    % Khớp 2 đến 6
    for i = 2:6
        zi_1 = T{i-1}(1:3, 3);
        pi_1 = T{i-1}(1:3, 4);
        J(1:3, i) = cross(zi_1, (P_ee - pi_1));
        J(4:6, i) = zi_1;
    end
    end
    %====================================================================
    function [isSingular, singType] = isSingularityPos(app, q_rad)
    % q_gain_deg: vector 1x6 góc khớp từ Slider (độ)
    isSingular = false;
    singType = 'None';
    
    % 1. Chuyển đổi sang Radian 
   
    
    % 2. Tính Ma trận Jacobian (Geometric Jacobian)
    J = app.calculateJacobian(q_rad);
    detJ = det(J);
    
    % 3. Kiểm tra ngưỡng kỳ dị (Thường là 1e-4)
    if abs(detJ) < 1e-4
        isSingular = true;
        
        % --- PHÂN LOẠI KỲ DỊ ---
        % A. Kỳ dị cổ tay (Wrist Singularity): Khớp 5 = 0 (sau offset)
        % Khi khớp 5 bằng 0, trục khớp 4 và 6 trùng nhau.
        if abs(sin(q_rad(5))) < 0.05
            singType = 'Wrist Singularity (Joint 5 is near 0°)';
            
        % B. Kỳ dị khuỷu tay (Elbow Singularity)
        % Xảy ra khi cánh tay duỗi thẳng hoặc gập hết mức (liên quan det của J vị trí)
        elseif abs(det(J(1:3, 1:3))) < 0.01
            singType = 'Elbow Singularity (Arm fully extended/retracted)';
            
        % C. Kỳ dị vai (Shoulder Singularity)
        % Tâm cổ tay nằm trên trục khớp 1 (xc = 0, yc = 0)
        else
            singType = 'Shoulder Singularity (Wrist center on Joint 1 axis)';
        end
    end
    end
    %====================================================================
    function runTrajectoryCubic(app)
    % --- 1. ĐỌC DỮ LIỆU HIỆN TẠI TỪ LABELS ---
    try
        curr_X = str2double(app.Val_CurX.Text);
        curr_Y = str2double(app.Val_CurY.Text);
        curr_Z = str2double(app.Val_CurZ.Text);
        
        % Loại bỏ ký tự ° để đổi sang số
        curr_Roll  = str2double(strrep(app.Val_CurRoll.Text, '°', ''));
        curr_Pitch = str2double(strrep(app.Val_CurPitch.Text, '°', ''));
        curr_Yaw   = str2double(strrep(app.Val_CurYaw.Text, '°', ''));
        
        P_start = [curr_X; curr_Y; curr_Z];
        RPY_start = [curr_Roll; curr_Pitch; curr_Yaw];
    catch
        uialert(app.UIFigure, 'Dữ liệu hiện tại không hợp lệ!', 'Lỗi');
        return;
    end

    % --- 2. LẤY ĐIỂM ĐÍCH TỪ GIAO DIỆN ---
    P_end = [app.Edit_TargX.Value; app.Edit_TargY.Value; app.Edit_TargZ.Value];
    RPY_end = [app.Slider_TargRoll.Value; app.Slider_TargPitch.Value; app.Slider_TargYaw.Value];

    % Sai lệch vị trí và hướng
    D_pos = P_end - P_start;
    D_rpy = zeros(3,1);
    for i = 1:3
        diff = RPY_end(i) - RPY_start(i);
        D_rpy(i) = mod(diff + 180, 360) - 180; % Quay theo cung ngắn nhất
    end

    % --- 3. CẤU HÌNH THỜI GIAN VÀ KHỞI TẠO ---
    T_total = max(0.1, app.timesEditField.Value); % Tổng thời gian đặt
    fs = 30;           % Tần số lấy mẫu (Hz)
    dt = 1/fs;         % Bước nhảy thời gian
    t_array = 0:dt:T_total;
    n = length(t_array);

    % Reset dữ liệu
    app.pathPoints = []; 
    app.traj_Time = t_array;
    app.traj_Pos = zeros(n, 3);
    app.traj_Vel = zeros(n, 3);
    app.traj_Acc = zeros(n, 3);

    % Biến lưu trạng thái trước để chống nhảy nghiệm
    q_prev_gain = [app.GainTheta1Slider.Value, app.GainTheta2Slider.Value, app.GainTheta3Slider.Value, ...
                   app.GainTheta4Slider.Value, app.GainTheta5Slider.Value, app.GainTheta6Slider.Value];

    % --- 4. VÒNG LẶP CHẠY QUỸ ĐẠO ---
    for k = 1:n
        % Tham số chuẩn hóa tau (0 đến 1)
        tau = t_array(k)/T_total;
        
        % Đa thức bậc 5 (Quintic Polynomial)
        s = 10*tau^3 - 15*tau^4 + 6*tau^5;
        sd = (30*tau^2 - 60*tau^3 + 30*tau^4) / T_total;
        sdd = (60*tau - 180*tau^2 + 120*tau^3) / T_total^2;

        % Tính toán tọa độ mong muốn tại bước k
        curr_P = P_start + s * D_pos;
        curr_RPY_step = RPY_start + s * D_rpy;

        % --- BƯỚC QUAN TRỌNG: GHI DỮ LIỆU TRƯỚC ---
        app.traj_Pos(k,:) = curr_P';
        app.traj_Vel(k,:) = (sd * D_pos)';
        app.traj_Acc(k,:) = (sdd * D_pos)';
        app.pathPoints = [app.pathPoints; curr_P']; % Thêm vết đường đi

        % Vẽ đồ thị PVA (Vẽ đến bước k hiện tại)
        app.plotPVA(app.traj_Time(1:k)', app.traj_Pos(1:k,:), app.traj_Vel(1:k,:), app.traj_Acc(1:k,:));

        % --- BƯỚC ĐIỀU KHIỂN ROBOT ---
        q_step_gain = app.calculateIK_Internal(curr_P, curr_RPY_step');

        if ~isempty(q_step_gain)
            % Kiểm tra kỳ dị (Singularity)
            offsets = [app.offset_J1, app.offset_J2, app.offset_J3, app.offset_J4, app.offset_J5, app.offset_J6];
            q_rad_actual = deg2rad(q_step_gain) + offsets;
            [isSingular, singType] = app.isSingularityPos(q_rad_actual);
            
            if isSingular
                uialert(app.UIFigure, ['Quỹ đạo dừng do gặp ' singType], 'Cảnh báo Kỳ dị');
                return; % Thoát hàm ngay lập tức
            end

            % Chống nhảy cấu hình (Jump Config)
            if k > 1 && any(abs(q_step_gain - q_prev_gain) > 35)
                % Nếu nhảy quá xa thì giữ nguyên q_prev, không cập nhật mô hình
            else
                % Cập nhật Sliders
                app.GainTheta1Slider.Value = q_step_gain(1);
                app.GainTheta2Slider.Value = q_step_gain(2);
                app.GainTheta3Slider.Value = q_step_gain(3);
                app.GainTheta4Slider.Value = q_step_gain(4);
                app.GainTheta5Slider.Value = q_step_gain(5);
                app.GainTheta6Slider.Value = q_step_gain(6);
                
                q_prev_gain = q_step_gain;
                app.updateRobot(); % Vẽ lại robot và đường đi
            end
        end

        % Cập nhật giao diện và tạo độ trễ thời gian thực
        drawnow limitrate;
        pause(dt); 
    end

    % --- 5. THÔNG BÁO HOÀN THÀNH ---
    msg = sprintf('Quỹ đạo Cubic đã hoàn thành!\nThời gian chạy: %.2f s', T_total);
    uialert(app.UIFigure, msg, 'Thành công', 'Icon', 'success');
end
    %======================================================================
    function runTrajectoryTrapezoidal(app, V_target,accel_input)
    % V_target: Vận tốc mong muốn (m/s)
    
    % --- 1. LẤY ĐIỂM ĐẦU VÀ ĐÍCH ---
    try
        P_start = [str2double(app.Val_CurX.Text); str2double(app.Val_CurY.Text); str2double(app.Val_CurZ.Text)];
        curr_RPY_start = [str2double(strrep(app.Val_CurRoll.Text,'°','')); ...
                          str2double(strrep(app.Val_CurPitch.Text,'°','')); ...
                          str2double(strrep(app.Val_CurYaw.Text,'°',''))];
        P_end = [app.Edit_TargX.Value; app.Edit_TargY.Value; app.Edit_TargZ.Value];
        RPY_end = [app.Slider_TargRoll.Value; app.Slider_TargPitch.Value; app.Slider_TargYaw.Value];
    catch
        return;
    end

    % --- 2. TÍNH TOÁN THÔNG SỐ HÌNH THANG ---
    D_vec = P_end - P_start;
    S_total = norm(D_vec); 
    
    accel = accel_input;
    tb = V_target / accel; 
    sb = 0.5 * accel * tb^2;     
    % Khởi tạo mặc định 
    sc = 0; 
    tc = 0;

    if 2*sb > S_total
        % TRƯỜNG HỢP HÌNH TAM GIÁC (Không có đoạn vận tốc hằng số)
        tb = sqrt(S_total / accel);
        V_max = accel * tb;
        sb = 0.5 * accel * tb^2; % Cập nhật lại sb theo tb mới
        sc = 0; % Quãng đường hằng số bằng 0
        tc = 0;
        T_total = 2 * tb;
    else
        % TRƯỜNG HỢP HÌNH THANG (Có đoạn vận tốc hằng số)
        V_max = V_target;
        sc = S_total - 2*sb; 
        tc = sc / V_max;    
        T_total = 2*tb + tc;
    end

    % --- 3. VÒNG LẶP CHẠY QUỸ ĐẠO ---
    fs = 30; 
    t_array = 0:(1/fs):T_total;
    n = length(t_array);
    
    % Lưu dữ liệu cho đồ thị
    app.traj_Time = t_array;
    app.traj_Pos = zeros(n, 3); app.traj_Vel = zeros(n, 3); app.traj_Acc = zeros(n, 3);
    q_prev = [app.GainTheta1Slider.Value, app.GainTheta2Slider.Value, app.GainTheta3Slider.Value, ...
              app.GainTheta4Slider.Value, app.GainTheta5Slider.Value, app.GainTheta6Slider.Value];
    % --- XÓA ĐƯỜNG ĐI CŨ KHI BẮT ĐẦU ---
    app.pathPoints = [];
    for k = 1:n
        t = t_array(k);
        % Tính quãng đường s(t), vận tốc v(t), gia tốc a(t)
        if t <= tb
            s_t = 0.5 * accel * t^2;
            v_t = accel * t;
            a_t = accel;
        elseif t <= (tb + tc)
            s_t = sb + V_max * (t - tb);
            v_t = V_max;
            a_t = 0;
        else
            t_dec = t - (tb + tc);
            s_t = sb + sc + V_max * t_dec - 0.5 * accel * t_dec^2;
            v_t = V_max - accel * t_dec;
            a_t = -accel;
        end
        
        % Tham số chuẩn hóa 0 -> 1
        u = s_t / S_total;
        curr_P = P_start + u * D_vec;
        
        % Nội suy hướng (ngắn nhất)
        D_rpy = zeros(3,1);
        for i = 1:3
            diff = RPY_end(i) - curr_RPY_start(i);
            D_rpy(i) = mod(diff + 180, 360) - 180;
        end
        curr_RPY = curr_RPY_start + u * D_rpy;
        % --- LƯU TỌA ĐỘ HIỆN TẠI VÀO DANH SÁCH ---
        app.pathPoints = [app.pathPoints; curr_P'];
        % Giải IK
        q_step = app.calculateIK_Internal(curr_P, curr_RPY');

        if ~isempty(q_step)
            % KIỂM TRA KỲ DỊ TRƯỚC KHI CẬP NHẬT (Yêu cầu BTL)
            q_rad = deg2rad(q_step) + [app.offset_J1, app.offset_J2, app.offset_J3, app.offset_J4, app.offset_J5, app.offset_J6];
            [isSingular, singType] = app.isSingularityPos(q_rad);
            
            if isSingular
                uialert(app.UIFigure, ['Dừng quỹ đạo do ' singType], 'Kỳ dị');
                return; % Thoát hàm
            end

            % Cập nhật Robot
            app.GainTheta1Slider.Value = q_step(1);
            app.GainTheta2Slider.Value = q_step(2);
            app.GainTheta3Slider.Value = q_step(3);
            app.GainTheta4Slider.Value = q_step(4);
            app.GainTheta5Slider.Value = q_step(5);
            app.GainTheta6Slider.Value = q_step(6);
            app.updateRobot();
        end

        % Lưu dữ liệu vector PVA
        unit_vec = D_vec / S_total;
        app.traj_Pos(k,:) = curr_P';
        app.traj_Vel(k,:) = (v_t * unit_vec)';
        app.traj_Acc(k,:) = (a_t * unit_vec)';
        
        % Vẽ đồ thị PVA
        app.plotPVA(t_array(1:k)', app.traj_Pos(1:k,:), app.traj_Vel(1:k,:), app.traj_Acc(1:k,:));
        drawnow limitrate;
    end
    msg = sprintf('Quỹ đạo đã hoàn thành!\nTổng thời gian: %.2f giây\nVận tốc mục tiêu: %.2f m/s', T_total, V_max);
    uialert(app.UIFigure, msg, 'Thông báo', 'Icon', 'success');
    end

    %=======================================================================
    function plotPVA(app, t, pos, vel, acc)
    % Vị trí (3 trục)
    plot(app.UIAxes_Pos, t, pos(:,1), 'r', t, pos(:,2), 'g', t, pos(:,3), 'b', 'LineWidth', 1.2);
    title(app.UIAxes_Pos, 'E.E Position (X,Y,Z)');
    grid(app.UIAxes_Pos, 'on');

    % Vận tốc (Magnitude)
    v_mag = sqrt(sum(vel.^2, 2));
    plot(app.UIAxes_Vel, t, v_mag, 'm', 'LineWidth', 1.5);
    title(app.UIAxes_Vel, 'E.E Velocity Magnitude (m/s)');
    grid(app.UIAxes_Vel, 'on');

    % Gia tốc (Magnitude)
    a_mag = sqrt(sum(acc.^2, 2));
    plot(app.UIAxes_Acc, t, a_mag, 'k', 'LineWidth', 1.5);
    title(app.UIAxes_Acc, 'E.E Acceleration Magnitude (m/s^2)');
    grid(app.UIAxes_Acc, 'on');
    end
    %====================================================================
    % Hàm cập nhật Robot và Vẽ đồ thị
        function updateRobot(app)
           if isempty(app.robot)
                return;
            end

            if isempty(app.robotAxes) || ~isvalid(app.robotAxes)
                return; 
            end
            
            
            % 1. Lấy giá trị từ Slider các khớp
            app.cur_theta1 = deg2rad(app.GainTheta1Slider.Value) + app.offset_J1;
            app.cur_theta2 = deg2rad(app.GainTheta2Slider.Value) + app.offset_J2;
            app.cur_theta3 = deg2rad(app.GainTheta3Slider.Value) + app.offset_J3;
            app.cur_theta4 = deg2rad(app.GainTheta4Slider.Value) + app.offset_J4;
            app.cur_theta5 = deg2rad(app.GainTheta5Slider.Value) + app.offset_J5;
            app.cur_theta6 = deg2rad(app.GainTheta6Slider.Value) + app.offset_J6;
            q = [app.cur_theta1
                 app.cur_theta2
                 app.cur_theta3
                 app.cur_theta4
                 app.cur_theta5
                 app.cur_theta6]';
            q_gain = deg2rad([
                app.GainTheta1Slider.Value
                app.GainTheta2Slider.Value
                app.GainTheta3Slider.Value
                app.GainTheta4Slider.Value
                app.GainTheta5Slider.Value
                app.GainTheta6Slider.Value
            ])';
            
            %Hiển thị
            app.label_cur_theta1.Text = sprintf('%.2f', rad2deg(app.cur_theta1));
            app.label_cur_theta2.Text = sprintf('%.2f', rad2deg(app.cur_theta2));
            app.label_cur_theta3.Text = sprintf('%.2f', rad2deg(app.cur_theta3));
            app.label_cur_theta4.Text = sprintf('%.2f', rad2deg(app.cur_theta4));
            app.label_cur_theta5.Text = sprintf('%.2f', rad2deg(app.cur_theta5));
            app.label_cur_theta6.Text = sprintf('%.2f', rad2deg(app.cur_theta6));
             
            % 2. Xóa và vẽ lại Robot
            XL = app.robotAxes.XLim;
            YL = app.robotAxes.YLim;
            ZL = app.robotAxes.ZLim;
            
            cla(app.robotAxes); 
            
            show(app.robot, q_gain, 'Parent', app.robotAxes, 'Visuals', 'on', 'Collisions', 'off');
            hold(app.robotAxes, 'on');
            % Vẽ đường đi (bổ sung)
            if ~isempty(app.pathPoints)
            plot3(app.robotAxes, app.pathPoints(:,1), app.pathPoints(:,2), app.pathPoints(:,3), ...
            'Color', [1 0 0], 'LineWidth', 1, 'LineStyle', '-'); 
            end
            % 3. TÍNH TOÁN ĐỘNG HỌC THUẬN
            T_FK = app.calculateFK(q);
            
            % 4. Vẽ hệ trục tọa độ End-Effector
            plotTransforms(tform2trvec(T_FK), tform2quat(T_FK), 'FrameSize', 0.3, 'Parent', app.robotAxes);
            hold(app.robotAxes, 'off');

            % 5. Cập nhật hiển thị vị trí end-effector
            pos_x = T_FK(1,4);
            pos_y = T_FK(2,4);
            pos_z = T_FK(3,4);
            
            app.Val_CurX.Text = sprintf('%.4f', pos_x);
            app.Val_CurY.Text = sprintf('%.4f', pos_y);
            app.Val_CurZ.Text = sprintf('%.4f', pos_z);
            % 6. Cập nhật hiển thị hướng end-effector
            % === TÍNH TOÁN ROLL - PITCH - YAW (RPY) ===
            % Trích xuất ma trận quay 3x3 từ T_FK
            R = T_FK(1:3, 1:3);
            
            % Tính Pitch (Góc xoay quanh Y)
            % Pitch = atan2(-r31, sqrt(r11^2 + r21^2))
            pitch_rad = atan2(-R(3,1), sqrt(R(1,1)^2 + R(2,1)^2));
            
            % Tính Yaw (Z) và Roll (X)
            % Kiểm tra điểm kỳ dị (Gimbal Lock) khi Pitch ~ +/- 90 độ
            if cos(pitch_rad) > 1e-6 % Không bị khóa
                % Roll = atan2(r32, r33)
                roll_rad = atan2(R(3,2), R(3,3));
                
                % Yaw = atan2(r21, r11)
                yaw_rad = atan2(R(2,1), R(1,1));
            else
                % Trường hợp đặc biệt: Pitch = 90 hoặc -90 độ
                % Lúc này trục X và Z trùng nhau, ta cố định Roll = 0
                roll_rad = 0;
                yaw_rad = atan2(-R(1,2), R(2,2));
            end
            % Cập nhật lên Giao diện
            app.Val_CurRoll.Text  = sprintf('%.2f°', rad2deg(roll_rad));
            app.Val_CurPitch.Text = sprintf('%.2f°', rad2deg(pitch_rad));
            app.Val_CurYaw.Text   = sprintf('%.2f°', rad2deg(yaw_rad));

            % 7. Thiết lập hiển thị Camera
            %show(app.robot, q, 'Parent', app.robotAxes, 'Visuals', 'on');
            view(app.robotAxes, 3);
            axis(app.robotAxes, 'equal');
            xlim(app.robotAxes, XL);
            ylim(app.robotAxes, YL); 
            zlim(app.robotAxes, ZL); 
            %Cố định Camera 
            camorbit(app.robotAxes,0,0);
            %
            
        end
    %=========================================================================

    %======================================================================
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      %%
        end

        % Value changed function: GainTheta1Slider
        function GainTheta1SliderValueChanged(app, event)
            %value = app.GainTheta1Slider.Value;
            updateRobot(app);
        end

        % Button pushed function: ButtonLoadRobot
        function ButtonLoadRobotPushed(app, event)
            [file, path] = uigetfile('*.urdf','Select Robot URDF');
    if isequal(file,0)
        return;
    end

    urdfFile = fullfile(path, file);

    % Import robot
    app.robot = importrobot(urdfFile);
    app.robot.DataFormat = 'row';
    app.robot.Gravity = [0 0 -9.81];
    %Khởi tạo các biến khớp ban đầu
    app.cur_theta1 = app.offset_J1;
    app.cur_theta2 = app.offset_J2;
    app.cur_theta3 = app.offset_J3;
    app.cur_theta4 = app.offset_J4;
    app.cur_theta5 = app.offset_J5;
    app.cur_theta6 = app.offset_J6;
    %Cập nhật label ban đầu
    app.label_cur_theta1.Text = sprintf('%.2f', rad2deg(app.cur_theta1));
    app.label_cur_theta2.Text = sprintf('%.2f', rad2deg(app.cur_theta2));
    app.label_cur_theta3.Text = sprintf('%.2f', rad2deg(app.cur_theta3));
    app.label_cur_theta4.Text = sprintf('%.2f', rad2deg(app.cur_theta4));
    app.label_cur_theta5.Text = sprintf('%.2f', rad2deg(app.cur_theta5));
    app.label_cur_theta6.Text = sprintf('%.2f', rad2deg(app.cur_theta6));
    % Tạo popup figure mới nếu cần
    if isempty(app.robotFigure) || ~isvalid(app.robotFigure)
        app.robotFigure = figure('Name','Robot View','NumberTitle','off');
        app.robotAxes = axes('Parent', app.robotFigure);
    else
        cla(app.robotAxes); % Xóa robot cũ
    end

    % Vẽ robot lần đầu
    show(app.robot, 'Parent', app.robotAxes, 'Visuals', 'on');
    view(app.robotAxes, 3);
    axis(app.robotAxes,'equal');
    % Giới hạn quan sát 
    xlim(app.robotAxes, [-1.8 1.8]); 
    ylim(app.robotAxes, [-1.8 1.8]); 
    zlim(app.robotAxes, [-0.5 2.5]);
    

    % KHÓA góc camera để không tự đổi
    camorbit(app.robotAxes, 0, 0);
    %Hiển thị cập nhật vị trí
    updateRobot(app);
        end

        % Value changed function: GainTheta2Slider
        function GainTheta2SliderValueChanged(app, event)
            %value = app.GainTheta2Slider.Value;
            updateRobot(app);
        end

        % Value changed function: GainTheta3Slider
        function GainTheta3SliderValueChanged(app, event)
            %value = app.GainTheta3Slider.Value;
            updateRobot(app);
        end

        % Value changed function: GainTheta4Slider
        function GainTheta4SliderValueChanged(app, event)
           % value = app.GainTheta4Slider.Value;
            updateRobot(app);
        end

        % Value changed function: GainTheta5Slider
        function GainTheta5SliderValueChanged(app, event)
           % value = app.GainTheta5Slider.Value;
            updateRobot(app);
        end

        % Value changed function: GainTheta6Slider
        function GainTheta6SliderValueChanged(app, event)
            %value = app.GainTheta6Slider.Value;
            updateRobot(app);
        end

        % Button pushed function: ButtonCalculateIK
        function ButtonCalculateIKPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Please load a robot first!', 'Warning');
                return;
            end

            % === 1. LẤY DỮ LIỆU ĐẦU VÀO ===
            x = app.Edit_TargX.Value(1);
            y = app.Edit_TargY.Value(1);
            z = app.Edit_TargZ.Value(1);
            
            % Đổi độ sang radian từ Slider
            roll = deg2rad(app.Slider_TargRoll.Value(1))  ;
            pitch = deg2rad(app.Slider_TargPitch.Value(1));
            yaw = deg2rad(app.Slider_TargYaw.Value(1)) ;

            % === 2. TÍNH MA TRẬN QUAY R_TOOL (Thứ tự XYZ cố định) ===
            Rx = [1 0 0; 0 cos(roll) -sin(roll); 0 sin(roll) cos(roll)];
            Ry = [cos(pitch) 0 sin(pitch); 0 1 0; -sin(pitch) 0 cos(pitch)];
            Rz = [cos(yaw) -sin(yaw) 0; sin(yaw) cos(yaw) 0; 0 0 1];
            
            R_tool = Rz * Ry * Rx;

            % Vector tiếp cận (Approach vector)
            ax = R_tool(1,3);
            ay = R_tool(2,3);
            az = R_tool(3,3);

            % === 3. THÔNG SỐ ROBOT (FANUC M-20iA/35M Standard DH) ===
            a1 = 0.150; d1 = 0.525;
            a2 = 0.790;
            a3 = 0.150; d4 = 0.860;
            d6 = 0.100;

            % === 4. TÍNH TÂM CỔ TAY (Wrist Center P_c) ===
            xc = x - d6 * ax;
            yc = y - d6 * ay;
            zc = z - d6 * az;

            % === 5. GIẢI VỊ TRÍ (Position - Theta 1, 2, 3) ===
            
            % --- Theta 1 ---
            % a1 là độ dài cánh tay ngang, không phải offset lệch vai -> Quay thẳng
            theta1 = atan2(yc, xc);

            % --- Tính toán trung gian cho Theta 2, 3 ---
            % SỬ DỤNG TOÁN TỬ .^ ĐỂ TRÁNH LỖI MATRIX POWER
            
            % Chiếu lên mặt phẳng cánh tay (trừ đi đoạn a1 đã vươn ra)
            r_effective = sqrt(xc.^2 + yc.^2) - a1;
            z_effective = zc - d1 ;                 
            
            % Bình phương khoảng cách từ Khớp 2 đến Tâm cổ tay
            D_sq = r_effective.^2 + z_effective.^2 ;        
            
            % Cạnh huyền ảo của khâu 3 (do cấu trúc chữ L)
            L34_sq = a3.^2 + d4.^2;          
            L34 = sqrt(L34_sq);
            a2_sq = a2.^2;

            % Định lý Cosin tính góc trong phi3
            cos_phi3 = (a2_sq + L34_sq - D_sq) ./ (2 * a2 * L34);
            
            if abs(cos_phi3) > 1
                uialert(app.UIFigure, 'Target nằm ngoài tầm với (Out of reach)!', 'IK Error');
                return;
            end
            
            % Góc lệch kết cấu beta
            beta = atan2(a3, d4); 
            
            % --- Theta 3 (CÔNG THỨC CHÍNH XÁC MỚI) ---
            % phi3: Góc trong tam giác (luôn dương)
            phi3 = atan2(sqrt(1 - cos_phi3.^2), cos_phi3);
            
            % Cấu hình Elbow Up, trừ đi beta và offset pi/2 của DH Spong
            theta3 = phi3 - beta - pi/2;

            % --- Theta 2 ---
            alpha_angle = atan2(z_effective, r_effective);
            cos_psi = (a2_sq + D_sq - L34_sq) ./ (2 * a2 * sqrt(D_sq));
            
            % Xử lý sai số số học nếu cos_psi hơi lớn hơn 1 một chút
            if cos_psi > 1, cos_psi = 1; end
            if cos_psi < -1, cos_psi = -1; end
            
            psi = atan2(sqrt(1 - cos_psi.^2), cos_psi);
           
            theta2 = alpha_angle + psi; % Cấu hình Elbow Up

            % === 6. GIẢI ĐỊNH HƯỚNG (Orientation - Theta 4, 5, 6) ===
            
            % Tính ma trận quay hiện tại R3_0 bằng FK
            c1 = cos(theta1); s1 = sin(theta1);
            c2 = cos(theta2); s2 = sin(theta2);
            c3 = cos(theta3); s3 = sin(theta3);
            
            % DH Matrix 1
            A1 = [c1 -s1*0  s1*1 a1*c1; s1 c1*0 -c1*1 a1*s1; 0 1 0 d1; 0 0 0 1]; 
            % DH Matrix 2
            A2 = [c2 -s2    0    a2*c2; s2 c2    0    a2*s2; 0 0 1 0;  0 0 0 1];
            % DH Matrix 3
            A3 = [c3 -s3*0  s3*1 a3*c3; s3 c3*0 -c3*1 a3*s3; 0 1 0 0;  0 0 0 1];

            T3_0 = A1 * A2 * A3;
            R3_0 = T3_0(1:3, 1:3);

            % Tính ma trận quay tương đối cổ tay
            R_wrist = R3_0' * R_tool;
            
            % Giải Euler Z-Y-Z (với alpha4=-90, alpha5=90)
            r13 = R_wrist(1,3);
            r23 = R_wrist(2,3);
            r31 = R_wrist(3,1);
            r32 = R_wrist(3,2);
            r33 = R_wrist(3,3);

            % Theta 5
            theta5 = atan2(sqrt(r13.^2 + r23.^2), r33);
            
            % Theta 4 & 6 (Xử lý kỳ dị)
            if sin(theta5) > 1e-4 || sin(theta5) < -1e-4
                theta4 = atan2(r23, r13);
                theta6 = atan2(r32, -r31);
            else
                % Kỳ dị: theta5 ~ 0. Giữ theta4 hiện tại
                theta4 = deg2rad(app.GainTheta4Slider.Value); 
                sum46 = atan2(-R_wrist(1,2), R_wrist(1,1));
                theta6 = sum46 - theta4;
            end
            q_inv = [theta1,theta2,theta3,theta4,theta5,theta6];
            if ~isempty(q_inv)
             % 3. KIỂM TRA KỲ DỊ TRƯỚC KHI CẬP NHẬT GIAO DIỆN
            [isSingular, singType] = app.isSingularityPos(q_inv);
        
            if isSingular
            % Nếu kỳ dị: Thông báo và DỪNG (không cập nhật Slider)
            msg = sprintf('Vị trí đích gây ra %s. Robot không thể thực hiện để tránh hư hỏng!', singType);
            uialert(app.UIFigure, msg, 'Lỗi Kỳ Dị');
            return; 
            end
            %Bù offset để cập nhật slider vẽ robot
            gain_theta1 = theta1 - app.offset_J1;
            gain_theta2 = theta2 - app.offset_J2;
            gain_theta3 = theta3 - app.offset_J3;
            gain_theta4 = theta4 - app.offset_J4;
            gain_theta5 = theta5 - app.offset_J5;
            gain_theta6 = theta6 - app.offset_J6;
            % === 7. CẬP NHẬT GIAO DIỆN ===
            q_deg = rad2deg([gain_theta1, gain_theta2, gain_theta3, gain_theta4, gain_theta5, gain_theta6]);
            
            % Wrap angle về khoảng [-180, 180]
            q_deg = wrapTo180(q_deg);

            app.GainTheta1Slider.Value = q_deg(1);
            app.GainTheta2Slider.Value = q_deg(2);
            app.GainTheta3Slider.Value = q_deg(3);
            app.GainTheta4Slider.Value = q_deg(4);
            app.GainTheta5Slider.Value = q_deg(5);
            app.GainTheta6Slider.Value = q_deg(6);
            
            % Cập nhật hình ảnh robot
            updateRobot(app);
               else
                uialert(app.UIFigure, 'Target nằm ngoài tầm với!', 'IK Error');
            end
        end

        % Button pushed function: HomePositionButton
        function HomePositionButtonPushed(app, event)
            app.GainTheta1Slider.Value = 0;
            app.GainTheta2Slider.Value = 0;
            app.GainTheta3Slider.Value = 0;
            app.GainTheta4Slider.Value = 0;
            app.GainTheta5Slider.Value = 0;
            app.GainTheta6Slider.Value = 0;
            app.pathPoints = []; % Xóa đường đi khi về Home
            updateRobot(app);
        end

        % Button pushed function: ButtonTrajectoryCubic
        function ButtonTrajectoryCubicPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Hãy Load Robot URDF trước!', 'Lỗi');
            return;
            end
        app.runTrajectoryCubic()
        end

        % Button pushed function: ButtonDrawWorkspace
        function ButtonDrawWorkspacePushed(app, event)
            % 1. Cấu hình số lượng điểm (càng nhiều càng rõ nhưng càng nặng)
    numPoints = 3000; 
    x_data = zeros(1, numPoints);
    y_data = zeros(1, numPoints);
    z_data = zeros(1, numPoints);

    % 2. Lấy giới hạn từ các Slider khớp
    limits = [app.GainTheta1Slider.Limits;
              app.GainTheta2Slider.Limits;
              app.GainTheta3Slider.Limits;
              app.GainTheta4Slider.Limits;
              app.GainTheta5Slider.Limits;
              app.GainTheta6Slider.Limits];

    % 3. Vòng lặp lấy mẫu ngẫu nhiên
    for i = 1:numPoints
        % Tạo bộ góc q ngẫu nhiên (đổi sang Radian)
        q_rand_deg = limits(:,1) + (limits(:,2) - limits(:,1)) .* rand(6,1);
        q_rand_rad = deg2rad(q_rand_deg' + [app.offset_J1, app.offset_J2, app.offset_J3, ...
                                           app.offset_J4, app.offset_J5, app.offset_J6]);
        
        % Tính FK (Sử dụng hàm calculateFK có sẵn của bạn)
        T = app.calculateFK(q_rand_rad);
        
        % Trích xuất tọa độ X, Y, Z
        x_data(i) = T(1,4);
        y_data(i) = T(2,4);
        z_data(i) = T(3,4);
    end

    % 4. Vẽ lên robotAxes
    hold(app.robotAxes, 'on');
    app.workspacePlotHandle = plot3(app.robotAxes, x_data, y_data, z_data, ...
        'b.', 'MarkerSize', 1, 'DisplayName', 'Workspace');
    hold(app.robotAxes, 'off');
        end

        % Button pushed function: ButtonClearWorkspace
        function ButtonClearWorkspacePushed(app, event)
            if ~isempty(app.workspacePlotHandle) && isvalid(app.workspacePlotHandle)
        delete(app.workspacePlotHandle);
        app.workspacePlotHandle = [];
            end
        app.pathPoints = []; % Xóa dữ liệu đường đi
        app.updateRobot();   % Vẽ lại để cập nhật màn hình
        end

        % Button pushed function: ButtonTrajectoryTrapezoidal
        function ButtonTrajectoryTrapezoidalPushed(app, event)
             if isempty(app.robot)
                uialert(app.UIFigure, 'Hãy Load Robot URDF trước!', 'Lỗi');
            return;
             end
             v_target = app.vmaxmsEditField.Value;
             accel_inpput = app.amaxmsEditField.Value;
            runTrajectoryTrapezoidal(app,v_target,accel_inpput);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Pos = uiaxes(app.UIFigure);
            title(app.UIAxes_Pos, 'Position (m)')
            xlabel(app.UIAxes_Pos, 't')
            ylabel(app.UIAxes_Pos, 'P')
            zlabel(app.UIAxes_Pos, 'Z')
            app.UIAxes_Vel = uiaxes(app.UIFigure);
            title(app.UIAxes_Vel, 'Velocity (m/s)')
            xlabel(app.UIAxes_Vel, 't')
            ylabel(app.UIAxes_Vel, 'v')
            zlabel(app.UIAxes_Vel, 'Z')
            app.UIAxes_Acc = uiaxes(app.UIFigure);
            title(app.UIAxes_Acc, 'Accelabration (m/s2)')
            xlabel(app.UIAxes_Acc, 't')
            ylabel(app.UIAxes_Acc, 'a')
            zlabel(app.UIAxes_Acc, 'Z')
            app.InverseKinematicsPanel = uipanel(app.UIFigure);
            app.InverseKinematicsPanel.Title = 'Inverse Kinematics';
            app.RollLabel = uilabel(app.InverseKinematicsPanel);
            app.RollLabel.Text = 'Target Roll';
            app.Slider_TargRoll = uislider(app.InverseKinematicsPanel);
            app.Slider_TargRoll.Limits = [-180 180];
            app.PitchLabel = uilabel(app.InverseKinematicsPanel);
            app.PitchLabel.Text = 'Target Pitch';
            app.Slider_TargPitch = uislider(app.InverseKinematicsPanel);
            app.Slider_TargPitch.Limits = [-180 180];
            app.YawLabel = uilabel(app.InverseKinematicsPanel);
            app.YawLabel.Text = {'Target Yaw'; ''};
            app.Slider_TargYaw = uislider(app.InverseKinematicsPanel);
            app.Slider_TargYaw.Limits = [-180 180];
            app.ButtonCalculateIK = uibutton(app.InverseKinematicsPanel, 'push');
            app.ButtonCalculateIK.ButtonPushedFcn = createCallbackFcn(app, @ButtonCalculateIKPushed, true);
            app.ButtonCalculateIK.Text = 'Button Calculate IK';
            app.XLabel = uilabel(app.InverseKinematicsPanel);
            app.XLabel.Text = 'Target X';
            app.Edit_TargX = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.YLabel = uilabel(app.InverseKinematicsPanel);
            app.YLabel.Text = 'Target Y';
            app.Edit_TargY = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.ZLabel = uilabel(app.InverseKinematicsPanel);
            app.ZLabel.Text = 'Target Z';
            app.Edit_TargZ = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.Panel_CurrentPosition = uipanel(app.UIFigure);
            app.Panel_CurrentPosition.Title = 'Current Position                  Current Orentation';
            app.XmLabel = uilabel(app.Panel_CurrentPosition);
            app.XmLabel.Text = 'X (m)';
            app.YmLabel = uilabel(app.Panel_CurrentPosition);
            app.YmLabel.Text = 'Y (m)';
            app.ZmLabel = uilabel(app.Panel_CurrentPosition);
            app.ZmLabel.Text = 'Z (m)';
            app.Val_CurX = uilabel(app.Panel_CurrentPosition);
            app.Val_CurX.Tag = 'Val_CurX ';
            app.Val_CurX.Text = {'0.00'; ''};
            app.Val_CurY = uilabel(app.Panel_CurrentPosition);
            app.Val_CurY.Text = {'0.00'; ''};
            app.Val_CurZ = uilabel(app.Panel_CurrentPosition);
            app.Val_CurZ.Text = '0.00';
            app.RolldegLabel = uilabel(app.Panel_CurrentPosition);
            app.RolldegLabel.Text = 'Roll (deg)';
            app.PitchdegLabel = uilabel(app.Panel_CurrentPosition);
            app.PitchdegLabel.Text = 'Pitch (deg)';
            app.YawdegLabel = uilabel(app.Panel_CurrentPosition);
            app.YawdegLabel.Text = 'Yaw (deg)';
            app.Val_CurRoll = uilabel(app.Panel_CurrentPosition);
            app.Val_CurRoll.Tag = 'Val_CurX ';
            app.Val_CurRoll.Text = {'0.00'; ''};
            app.Val_CurPitch = uilabel(app.Panel_CurrentPosition);
            app.Val_CurPitch.Text = {'0.00'; ''};
            app.Val_CurYaw = uilabel(app.Panel_CurrentPosition);
            app.Val_CurYaw.Text = '0.00';
            app.Panel_Forward_Kinematic = uipanel(app.UIFigure);
            app.Panel_Forward_Kinematic.Title = 'Forward Kinematics';
            app.GainTheta1SliderLabel = uilabel(app.Panel_Forward_Kinematic);
            app.GainTheta1SliderLabel.Text = 'Gain Theta 1';
            app.GainTheta1Slider = uislider(app.Panel_Forward_Kinematic);
            app.GainTheta1Slider.Limits = [-180 180];
            app.GainTheta1Slider.ValueChangedFcn = createCallbackFcn(app, @GainTheta1SliderValueChanged, true);
            app.GainTheta1Slider.Tag = 'Theta1Slider';
            app.GainTheta2SliderLabel = uilabel(app.Panel_Forward_Kinematic);
            app.GainTheta2SliderLabel.Text = 'Gain Theta 2';
            app.GainTheta2Slider = uislider(app.Panel_Forward_Kinematic);
            app.GainTheta2Slider.Limits = [-180 180];
            app.GainTheta2Slider.ValueChangedFcn = createCallbackFcn(app, @GainTheta2SliderValueChanged, true);
            app.GainTheta2Slider.Tag = 'Theta2Slider';
            app.GainTheta3SliderLabel = uilabel(app.Panel_Forward_Kinematic);
            app.GainTheta3SliderLabel.Text = 'GainTheta 3';
            app.GainTheta3Slider = uislider(app.Panel_Forward_Kinematic);
            app.GainTheta3Slider.Limits = [-180 180];
            app.GainTheta3Slider.ValueChangedFcn = createCallbackFcn(app, @GainTheta3SliderValueChanged, true);
            app.GainTheta3Slider.Tag = 'Theta3Slider';
            app.GainTheta4SliderLabel = uilabel(app.Panel_Forward_Kinematic);
            app.GainTheta4SliderLabel.Text = 'Gain Theta 4';
            app.GainTheta4Slider = uislider(app.Panel_Forward_Kinematic);
            app.GainTheta4Slider.Limits = [-180 180];
            app.GainTheta4Slider.ValueChangedFcn = createCallbackFcn(app, @GainTheta4SliderValueChanged, true);
            app.GainTheta4Slider.Tag = 'Theta4Slider';
            app.GainTheta5SliderLabel = uilabel(app.Panel_Forward_Kinematic);
            app.GainTheta5SliderLabel.Tag = 'LoadRobotButton';
            app.GainTheta5SliderLabel.Text = {'Gain Theta 5'; ''};
            app.GainTheta5Slider = uislider(app.Panel_Forward_Kinematic);
            app.GainTheta5Slider.Limits = [-180 180];
            app.GainTheta5Slider.ValueChangedFcn = createCallbackFcn(app, @GainTheta5SliderValueChanged, true);
            app.GainTheta5Slider.Tag = 'Theta5Slider';
            app.GainTheta6SliderLabel = uilabel(app.Panel_Forward_Kinematic);
            app.GainTheta6SliderLabel.Text = 'Gain Theta 6';
            app.GainTheta6Slider = uislider(app.Panel_Forward_Kinematic);
            app.GainTheta6Slider.Limits = [-180 180];
            app.GainTheta6Slider.ValueChangedFcn = createCallbackFcn(app, @GainTheta6SliderValueChanged, true);
            app.GainTheta6Slider.Tag = 'Theta6Slider';
            app.Theta1degLabel = uilabel(app.Panel_Forward_Kinematic);
            app.Theta1degLabel.Text = 'Theta 1 (deg)';
            app.Theta2degLabel = uilabel(app.Panel_Forward_Kinematic);
            app.Theta2degLabel.Text = 'Theta 2 (deg)';
            app.Theta3degLabel = uilabel(app.Panel_Forward_Kinematic);
            app.Theta3degLabel.Text = 'Theta 3 (deg)';
            app.Theta4degLabel = uilabel(app.Panel_Forward_Kinematic);
            app.Theta4degLabel.Text = 'Theta 4 (deg)';
            app.Theta5degLabel = uilabel(app.Panel_Forward_Kinematic);
            app.Theta5degLabel.Text = 'Theta 5 (deg)';
            app.Theta6degLabel = uilabel(app.Panel_Forward_Kinematic);
            app.Theta6degLabel.Text = 'Theta 6 (deg)';
            app.label_cur_theta1 = uilabel(app.Panel_Forward_Kinematic);
            app.label_cur_theta1.Text = '0.0';
            app.label_cur_theta2 = uilabel(app.Panel_Forward_Kinematic);
            app.label_cur_theta2.Text = '0.0';
            app.label_cur_theta3 = uilabel(app.Panel_Forward_Kinematic);
            app.label_cur_theta3.Text = '0.0';
            app.label_cur_theta4 = uilabel(app.Panel_Forward_Kinematic);
            app.label_cur_theta4.Text = '0.0';
            app.label_cur_theta5 = uilabel(app.Panel_Forward_Kinematic);
            app.label_cur_theta5.Text = '0.0';
            app.label_cur_theta6 = uilabel(app.Panel_Forward_Kinematic);
            app.label_cur_theta6.Text = '0.0';
            app.BitplnMnKthutRobotTN01Label = uilabel(app.UIFigure);
            app.BitplnMnKthutRobotTN01Label.Text = 'Bài tập lớn Môn Kỹ thuật Robot - TN01';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logoBK.png';
            app.GVHDTSTrnNgcHuyLabel = uilabel(app.UIFigure);
            app.GVHDTSTrnNgcHuyLabel.Text = 'GVHD: TS. Trần Ngọc Huy';
            app.SVTHLVnNht2212393LuTrngNgha2212223Label = uilabel(app.UIFigure);
            app.SVTHLVnNht2212393LuTrngNgha2212223Label.Text = {'SVTH: Lê Văn Nhật - 2212393 & Lưu Trọng Nghĩa - 2212223'; ' '};
            app.ButtonLoadRobot = uibutton(app.UIFigure, 'push');
            app.ButtonLoadRobot.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadRobotPushed, true);
            app.ButtonLoadRobot.Tag = 'LoadRobotButton';
            app.ButtonLoadRobot.Text = 'Button Load Robot';
            app.ButtonDrawWorkspace = uibutton(app.UIFigure, 'push');
            app.ButtonDrawWorkspace.ButtonPushedFcn = createCallbackFcn(app, @ButtonDrawWorkspacePushed, true);
            app.ButtonDrawWorkspace.Tag = 'LoadRobotButton';
            app.ButtonDrawWorkspace.Text = 'Draw Workspace';
            app.ButtonClearWorkspace = uibutton(app.UIFigure, 'push');
            app.ButtonClearWorkspace.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearWorkspacePushed, true);
            app.ButtonClearWorkspace.Tag = 'LoadRobotButton';
            app.ButtonClearWorkspace.Text = 'Clear Workspace';
            app.HomePositionButton = uibutton(app.UIFigure, 'push');
            app.HomePositionButton.ButtonPushedFcn = createCallbackFcn(app, @HomePositionButtonPushed, true);
            app.HomePositionButton.Text = 'Home Position';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Cubic';
            app.ButtonTrajectoryCubic = uibutton(app.Tab1, 'push');
            app.ButtonTrajectoryCubic.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrajectoryCubicPushed, true);
            app.ButtonTrajectoryCubic.Text = {'Trajectory planning '; '(Cubic)'};
            app.tsLabel = uilabel(app.Tab1);
            app.tsLabel.Text = 'time (s)';
            app.timesEditField = uieditfield(app.Tab1, 'numeric');
            app.timesEditField.Value = 4;
            app.TrapezoidalTab = uitab(app.TabGroup);
            app.TrapezoidalTab.Title = 'Trapezoidal';
            app.ButtonTrajectoryTrapezoidal = uibutton(app.TrapezoidalTab, 'push');
            app.ButtonTrajectoryTrapezoidal.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrajectoryTrapezoidalPushed, true);
            app.ButtonTrajectoryTrapezoidal.Text = {'Trajectory planning '; '(Trapezoidal)'};
            app.vmaxmsEditFieldLabel = uilabel(app.TrapezoidalTab);
            app.vmaxmsEditFieldLabel.Text = 'v max (m/s)';
            app.vmaxmsEditField = uieditfield(app.TrapezoidalTab, 'numeric');
            app.vmaxmsEditField.Value = 1;
            app.amaxmsEditFieldLabel = uilabel(app.TrapezoidalTab);
            app.amaxmsEditFieldLabel.Text = 'a max (m/s)';
            app.amaxmsEditField = uieditfield(app.TrapezoidalTab, 'numeric');
            app.amaxmsEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_BTL_Robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end